classdef do_an_THDSP_piano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FSharp5                        matlab.ui.control.Button
        ASharp4                        matlab.ui.control.Button
        DSharp5                        matlab.ui.control.Button
        CSharp5                        matlab.ui.control.Button
        GSharp4                        matlab.ui.control.Button
        CSharp4                        matlab.ui.control.Button
        C4                             matlab.ui.control.Button
        D4                             matlab.ui.control.Button
        E4                             matlab.ui.control.Button
        F4                             matlab.ui.control.Button
        G4                             matlab.ui.control.Button
        A4                             matlab.ui.control.Button
        B4                             matlab.ui.control.Button
        C5                             matlab.ui.control.Button
        D5                             matlab.ui.control.Button
        E5                             matlab.ui.control.Button
        F5                             matlab.ui.control.Button
        FSharp4                        matlab.ui.control.Button
        DSharp4                        matlab.ui.control.Button
        G5                             matlab.ui.control.Button
        A5                             matlab.ui.control.Button
        B5                             matlab.ui.control.Button
        GSharp5                        matlab.ui.control.Button
        ASharp5                        matlab.ui.control.Button
        AppmphngnpianosdngsngsinLabel  matlab.ui.control.Label
        ChnditrngButtonGroup           matlab.ui.container.ButtonGroup
        Button_8                       matlab.ui.control.RadioButton
        Button_9                       matlab.ui.control.RadioButton
        Button_11                      matlab.ui.control.RadioButton
    end

 methods (Access = private)
        function play(app, tanso)
            if(app.Button_8.Value)
                value=2;
            elseif(app.Button_9.Value)
                value=1;
            elseif(app.Button_11.Value)
                value=4;
            end
            
            laymau  = 1/50000;
            beat = 0;
            gtri_thoigian   = 0.3488*value;
            sl_laymau     = 0:laymau:gtri_thoigian;

            beat = sin(2*pi*tanso*sl_laymau);

            sound(beat, 1/laymau)

        end
        
 end


    methods (Access = private)

        % Button pushed function: C4
        function key_C4(app, event)
            tanso = 261.625;
            play(app,tanso);
        end

        % Button pushed function: D4
        function key_D4(app, event)
            tanso = 293.66;
            play(app,tanso);
        end

        % Button pushed function: E4
        function key_E4(app, event)
            tanso = 329.63;
            play(app,tanso);
        end

        % Button pushed function: F4
        function key_F4(app, event)
            tanso = 349.23;
            play(app,tanso);
        end

        % Button pushed function: G4
        function key_G4(app, event)
            tanso = 392;
            play(app,tanso);
        end

        % Button pushed function: A4
        function key_A4(app, event)
            tanso = 440;
            play(app,tanso);
        end

        % Button pushed function: B4
        function key_B4(app, event)
            tanso = 493.88;
            play(app,tanso);
        end

        % Button pushed function: C5
        function key_C5(app, event)
          tanso = 523.25;
          play(app,tanso);
        end

        % Button pushed function: D5
        function key_D5(app, event)
            tanso = 587.33;
            play(app,tanso);
        end

        % Button pushed function: E5
        function key_E5(app, event)
            tanso = 659.26;
            play(app,tanso);
        end

        % Button pushed function: F5
        function key_F5(app, event)
            tanso = 698.46;
            play(app,tanso);
        end

        % Button pushed function: G5
        function key_G5(app, event)
            tanso = 783.99;
            play(app,tanso);
        end

        % Button pushed function: A5
        function key_A5(app, event)
            tanso = 880;
            play(app,tanso);
        end

        % Button pushed function: B5
        function key_B5(app, event)
            tanso = 987.77;
            play(app,tanso);
        end

        % Button pushed function: CSharp4
        function key_CSharp4(app, event)
            tanso = 277.18;
            play(app,tanso);
        end

        % Button pushed function: DSharp4
        function key_DSharp4(app, event)
            tanso = 311.13;
            play(app,tanso);
        end

        % Button pushed function: FSharp4
        function key_FSharp4(app, event)
            tanso = 369.99;
            play(app,tanso);

        end

        % Button pushed function: GSharp4
        function key_GSharp4(app, event)
            tanso = 415.3;
            play(app,tanso);
        end

        % Button pushed function: ASharp4
        function key_ASharp4(app, event)
            tanso = 466.16;
            play(app,tanso);
        end

        % Button pushed function: CSharp5
        function key_CSharp5(app, event)
            tanso = 554.37;
            play(app,tanso);
        end

        % Button pushed function: DSharp5
        function key_DSharp5(app, event)
            tanso = 622.25;
            play(app,tanso);
        end

        % Button pushed function: FSharp5
        function key_FSharp5(app, event)
            tanso = 739.99;
            play(app,tanso);
        end

        % Button pushed function: GSharp5
        function key_GSharp5(app, event)
            
            tanso = 830.61;
            play(app,tanso);
        end

        % Button pushed function: ASharp5
        function key_ASharp5(app, event)
            
            freq = 932.33;
            play(app,freq);
        end

        % Selection changed function: ChnditrngButtonGroup
        function ChnditrngButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChnditrngButtonGroup.SelectedObject;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FSharp5 = uibutton(app.UIFigure, 'push');
            app.FSharp5.ButtonPushedFcn = createCallbackFcn(app, @key_FSharp5, true);
            app.FSharp5.Text = 'F#';
            app.ASharp4 = uibutton(app.UIFigure, 'push');
            app.ASharp4.ButtonPushedFcn = createCallbackFcn(app, @key_ASharp4, true);
            app.ASharp4.Text = 'A#';
            app.DSharp5 = uibutton(app.UIFigure, 'push');
            app.DSharp5.ButtonPushedFcn = createCallbackFcn(app, @key_DSharp5, true);
            app.DSharp5.Text = 'D#';
            app.CSharp5 = uibutton(app.UIFigure, 'push');
            app.CSharp5.ButtonPushedFcn = createCallbackFcn(app, @key_CSharp5, true);
            app.CSharp5.Text = 'C#';
            app.GSharp4 = uibutton(app.UIFigure, 'push');
            app.GSharp4.ButtonPushedFcn = createCallbackFcn(app, @key_GSharp4, true);
            app.GSharp4.Text = 'G#';
            app.CSharp4 = uibutton(app.UIFigure, 'push');
            app.CSharp4.ButtonPushedFcn = createCallbackFcn(app, @key_CSharp4, true);
            app.CSharp4.Text = 'C#';
            app.C4 = uibutton(app.UIFigure, 'push');
            app.C4.ButtonPushedFcn = createCallbackFcn(app, @key_C4, true);
            app.C4.Text = 'C4';
            app.D4 = uibutton(app.UIFigure, 'push');
            app.D4.ButtonPushedFcn = createCallbackFcn(app, @key_D4, true);
            app.D4.Text = 'D4';
            app.E4 = uibutton(app.UIFigure, 'push');
            app.E4.ButtonPushedFcn = createCallbackFcn(app, @key_E4, true);
            app.E4.Text = 'E4';
            app.F4 = uibutton(app.UIFigure, 'push');
            app.F4.ButtonPushedFcn = createCallbackFcn(app, @key_F4, true);
            app.F4.Text = 'F4';
            app.G4 = uibutton(app.UIFigure, 'push');
            app.G4.ButtonPushedFcn = createCallbackFcn(app, @key_G4, true);
            app.G4.Text = 'G4';
            app.A4 = uibutton(app.UIFigure, 'push');
            app.A4.ButtonPushedFcn = createCallbackFcn(app, @key_A4, true);
            app.A4.Text = 'A4';
            app.B4 = uibutton(app.UIFigure, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @key_B4, true);
            app.B4.Text = 'B4';
            app.C5 = uibutton(app.UIFigure, 'push');
            app.C5.ButtonPushedFcn = createCallbackFcn(app, @key_C5, true);
            app.C5.Text = 'C5';
            app.D5 = uibutton(app.UIFigure, 'push');
            app.D5.ButtonPushedFcn = createCallbackFcn(app, @key_D5, true);
            app.D5.Text = 'D5';
            app.E5 = uibutton(app.UIFigure, 'push');
            app.E5.ButtonPushedFcn = createCallbackFcn(app, @key_E5, true);
            app.E5.Text = 'E5';
            app.F5 = uibutton(app.UIFigure, 'push');
            app.F5.ButtonPushedFcn = createCallbackFcn(app, @key_F5, true);
            app.F5.Text = 'F5';
            app.FSharp4 = uibutton(app.UIFigure, 'push');
            app.FSharp4.ButtonPushedFcn = createCallbackFcn(app, @key_FSharp4, true);
            app.FSharp4.Text = 'F#';
            app.DSharp4 = uibutton(app.UIFigure, 'push');
            app.DSharp4.ButtonPushedFcn = createCallbackFcn(app, @key_DSharp4, true);
            app.DSharp4.Text = 'D#';
            app.G5 = uibutton(app.UIFigure, 'push');
            app.G5.ButtonPushedFcn = createCallbackFcn(app, @key_G5, true);
            app.G5.Text = 'G5';
            app.A5 = uibutton(app.UIFigure, 'push');
            app.A5.ButtonPushedFcn = createCallbackFcn(app, @key_A5, true);
            app.A5.Text = 'A5';
            app.B5 = uibutton(app.UIFigure, 'push');
            app.B5.ButtonPushedFcn = createCallbackFcn(app, @key_B5, true);
            app.B5.Text = 'B5';
            app.GSharp5 = uibutton(app.UIFigure, 'push');
            app.GSharp5.ButtonPushedFcn = createCallbackFcn(app, @key_GSharp5, true);
            app.GSharp5.Text = 'G#';
            app.ASharp5 = uibutton(app.UIFigure, 'push');
            app.ASharp5.ButtonPushedFcn = createCallbackFcn(app, @key_ASharp5, true);
            app.ASharp5.Text = 'A#';
            app.AppmphngnpianosdngsngsinLabel = uilabel(app.UIFigure);
            app.AppmphngnpianosdngsngsinLabel.Text = 'App mô phỏng đàn piano (sử dụng sóng sin)';
            app.ChnditrngButtonGroup = uibuttongroup(app.UIFigure);
            app.ChnditrngButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChnditrngButtonGroupSelectionChanged, true);
            app.ChnditrngButtonGroup.Title = 'Chọn độ dài trường độ';
            app.Button_8 = uiradiobutton(app.ChnditrngButtonGroup);
            app.Button_8.Text = ' 1/2';
            app.Button_9 = uiradiobutton(app.ChnditrngButtonGroup);
            app.Button_9.Text = ' 1/4';
            app.Button_11 = uiradiobutton(app.ChnditrngButtonGroup);
            app.Button_11.Text = '  1 ';
            app.Button_11.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = do_an_THDSP_piano

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end